classdef Calc_torque < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CalculadoraTorqueUIFigure  matlab.ui.Figure
        Btnlimpiar                 matlab.ui.control.Button
        Image                      matlab.ui.control.Image
        label2                     matlab.ui.control.Label
        NcdotmLabel                matlab.ui.control.Label
        resultado                  matlab.ui.control.NumericEditField
        TorqueLabel                matlab.ui.control.Label
        CalcularButton             matlab.ui.control.Button
        ParmetrosPanel             matlab.ui.container.Panel
        CilindrajeccLabel          matlab.ui.control.Label
        escogerCilindraje          matlab.ui.control.DropDown
        editDistancia              matlab.ui.control.NumericEditField
        DistanciamLabel            matlab.ui.control.Label
        editFuerza                 matlab.ui.control.NumericEditField
        FuerzaNLabel               matlab.ui.control.Label
        label1                     matlab.ui.control.Label
        CalculadoradeTorqueLabel   matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
           
           %% TÍTULO: CALCULADORA TORQUE
           %% NOMBRE: Diego Cevallos
           %% Este programa permite calcular  
           %% el torque de una moto dependiendo si su cilindraje es 2 o 4
           %% respectivamente


             % se recuperan las variables
            fuerza = app.editFuerza.Value; % fuerza
            distancia = app.editDistancia.Value; % distancia
        
                 
            % A continuacion se calcula el torque
            opc = app.escogerCilindraje.Value; % opcion de escoger el cilindraje
            
           
            % dependiendo la opcion, se calcula Pot recibida
            if strcmp(opc,'2')
                torque = fuerza*distancia;
            elseif strcmp(opc,'4')
                torque = fuerza*distancia*2;
            end
            
            % El resultado se muestra 
            app.resultado.Value = torque;
                    
        end

        % Close request function: CalculadoraTorqueUIFigure
        function CalculadoraTorqueUIFigureCloseRequest(app, event)
            delete(app)
            clc;
            clear all;
        end

        % Button pushed function: Btnlimpiar
        function BtnlimpiarButtonPushed(app, event)
            app.editFuerza.Value=0;
            app.editDistancia.Value=0;
            app.resultado.Value=0;
            % limpieza de variables para nuevos calculos
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CalculadoraTorqueUIFigure = uifigure('Visible', 'off');
            app.CalculadoraTorqueUIFigure.Name = 'Calculadora Torque';
            app.CalculadoraTorqueUIFigure.CloseRequestFcn = createCallbackFcn(app, @CalculadoraTorqueUIFigureCloseRequest, true);
            app.CalculadoradeTorqueLabel = uilabel(app.CalculadoraTorqueUIFigure);
            app.CalculadoradeTorqueLabel.Text = 'Calculadora de Torque';
            app.label1 = uilabel(app.CalculadoraTorqueUIFigure);
            app.label1.Interpreter = 'latex';
            app.label1.Text = '$$ Fuerza \; Rotacional = Torque = \vec{\tau}$$';
            app.ParmetrosPanel = uipanel(app.CalculadoraTorqueUIFigure);
            app.ParmetrosPanel.Title = 'Parámetros';
            app.FuerzaNLabel = uilabel(app.ParmetrosPanel);
            app.FuerzaNLabel.Text = 'Fuerza [N]';
            app.editFuerza = uieditfield(app.ParmetrosPanel, 'numeric');
            app.DistanciamLabel = uilabel(app.ParmetrosPanel);
            app.DistanciamLabel.Text = 'Distancia [m]';
            app.editDistancia = uieditfield(app.ParmetrosPanel, 'numeric');
            app.escogerCilindraje = uidropdown(app.ParmetrosPanel);
            app.escogerCilindraje.Items = {'2', '4'};
            app.escogerCilindraje.Value = '2';
            app.CilindrajeccLabel = uilabel(app.ParmetrosPanel);
            app.CilindrajeccLabel.Text = 'Cilindraje [cc]';
            app.CalcularButton = uibutton(app.CalculadoraTorqueUIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.TorqueLabel = uilabel(app.CalculadoraTorqueUIFigure);
            app.TorqueLabel.Text = 'Torque';
            app.resultado = uieditfield(app.CalculadoraTorqueUIFigure, 'numeric');
            app.resultado.ValueDisplayFormat = '%.2f';
            app.resultado.Editable = 'off';
            app.NcdotmLabel = uilabel(app.CalculadoraTorqueUIFigure);
            app.NcdotmLabel.Interpreter = 'latex';
            app.NcdotmLabel.Text = '$$[N \cdot m]$$';
            app.label2 = uilabel(app.CalculadoraTorqueUIFigure);
            app.label2.Interpreter = 'latex';
            app.label2.Text = '$$\vec{\tau} = \vec{r} \times \vec{F}$$';
            app.Image = uiimage(app.CalculadoraTorqueUIFigure);
            app.Image.ImageSource = 'torq_im.jpeg';
            app.Btnlimpiar = uibutton(app.CalculadoraTorqueUIFigure, 'push');
            app.Btnlimpiar.ButtonPushedFcn = createCallbackFcn(app, @BtnlimpiarButtonPushed, true);
            app.Btnlimpiar.Text = 'Limpiar';
            % Show the figure after all components are created
            app.CalculadoraTorqueUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Calc_torque

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CalculadoraTorqueUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CalculadoraTorqueUIFigure)
        end
    end
end